classdef loginForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IMSUIFigure                  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        Hyperlink_2                  matlab.ui.control.Hyperlink
        DropDown                     matlab.ui.control.DropDown
        Hyperlink                    matlab.ui.control.Hyperlink
        ProtectedAccountAccessLabel  matlab.ui.control.Label
        SubmitButton                 matlab.ui.control.Button
        EditField                    matlab.ui.control.EditField
        UsernameEditField            matlab.ui.control.EditField
        LOGINTOYOURACCOUNTLabel      matlab.ui.control.Label
        Image3                       matlab.ui.control.Image
        Button                       matlab.ui.control.Button
        GetstartedbysigningupLabel   matlab.ui.control.Label
        NewHereLabel                 matlab.ui.control.Label
        Image2                       matlab.ui.control.Image
        Image                        matlab.ui.control.Image
    end

    
    properties (Access = public)
        username
        role % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
                app.role = app.DropDown.Value;
                app.username = app.UsernameEditField.Value;
                password = app.EditField.Value;
              
            
                % Validate username
                if strcmp(app.role, 'Choose Your Role')
                    msgbox('Please choose a role from the dropdown.', 'Error', 'error');
                    return;
                end

                if isempty(app.username)
                    msgbox('Username cannot be empty.', 'Error', 'error');
                    return;
                end
            
                % Validate password
                if isempty(password)
                    msgbox('Password cannot be empty.', 'Error', 'error');
                    return;
                end
            
                app.DropDown.Value = 'Choose Your Role';
                app.UsernameEditField.Value = '';
                app.EditField.Value = '';   
                generateAndSavePDFReport(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.IMSUIFigure = uifigure('Visible', 'off');
            app.IMSUIFigure.Name = 'IMS';
            app.IMSUIFigure.Icon = fullfile(pathToMLAPP, 'N-Logo.png');
            app.GridLayout = uigridlayout(app.IMSUIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'nigas1-removebg-preview.png');
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.Tag = 'hPB4';
            app.Image2.ImageSource = fullfile(pathToMLAPP, '590Dark Blue Abstract Low Poly Background.jpg');
            app.NewHereLabel = uilabel(app.GridLayout);
            app.NewHereLabel.Text = 'New Here !';
            app.GetstartedbysigningupLabel = uilabel(app.GridLayout);
            app.GetstartedbysigningupLabel.Text = 'Get started by signing up';
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.Text = '';
            app.Image3 = uiimage(app.GridLayout);
            app.Image3.ImageSource = fullfile(pathToMLAPP, '590Dark Blue Abstract Low Poly Background.jpg');
            app.LOGINTOYOURACCOUNTLabel = uilabel(app.GridLayout);
            app.LOGINTOYOURACCOUNTLabel.Text = 'LOGIN TO YOUR ACCOUNT';
            app.UsernameEditField = uieditfield(app.GridLayout, 'text');
            app.UsernameEditField.Placeholder = 'Username';
            app.EditField = uieditfield(app.GridLayout, 'text');
            app.EditField.InputType = 'digits';
            app.EditField.Placeholder = 'Password';
            app.SubmitButton = uibutton(app.GridLayout, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.ProtectedAccountAccessLabel = uilabel(app.GridLayout);
            app.ProtectedAccountAccessLabel.Text = 'Protected Account Access';
            app.Hyperlink = uihyperlink(app.GridLayout);
            app.Hyperlink.Text = 'Forgot Password ?';
            app.DropDown = uidropdown(app.GridLayout);
            app.DropDown.Items = {'Choose Your Role', 'Admin', 'Manager', 'User'};
            app.DropDown.Value = 'Choose Your Role';
            app.Hyperlink_2 = uihyperlink(app.GridLayout);
            app.Hyperlink_2.URL = 'Signup Form\signupform.mlapp';
            app.Hyperlink_2.Text = 'Sign Up';
            % Show the figure after all components are created
            app.IMSUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = loginForm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IMSUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IMSUIFigure)
        end
    end
end